classdef DutyCycleApp2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainGridLayout                  matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        WorkingConditionTab             matlab.ui.container.Tab
        WCGridLayout                    matlab.ui.container.GridLayout
        VisualizationAndStartGridLayout  matlab.ui.container.GridLayout
        StartPanel                      matlab.ui.container.Panel
        StartGridLayout                 matlab.ui.container.GridLayout
        StartLamp                       matlab.ui.control.Lamp
        StartButton                     matlab.ui.control.Button
        DutyCycleUIAxes                 matlab.ui.control.UIAxes
        InputPanel                      matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        FullDieselPanel                 matlab.ui.container.Panel
        InputConsumptionFulDieselGridLayout  matlab.ui.container.GridLayout
        FC_unitDieselLabel_5            matlab.ui.control.Label
        EngineEfficiencyDieselEditField  matlab.ui.control.NumericEditField
        EngineEfficiencyEditFieldLabel  matlab.ui.control.Label
        FC_unitDieselLabel_4            matlab.ui.control.Label
        WorkingTimeDieselEditField      matlab.ui.control.NumericEditField
        WorkingTimeEditFieldLabel       matlab.ui.control.Label
        FC_unitDieselLabel_3            matlab.ui.control.Label
        DieseldensityDieselEditField    matlab.ui.control.NumericEditField
        DieseldensityEditFieldLabel     matlab.ui.control.Label
        FC_unitDieselLabel_2            matlab.ui.control.Label
        SpecificDieselPowerDieselEditField  matlab.ui.control.NumericEditField
        LHVLabel_2                      matlab.ui.control.Label
        FC_unitDieselLabel              matlab.ui.control.Label
        FuelConsumptionDieselEditField  matlab.ui.control.NumericEditField
        FuelConsumptionEditFieldLabel   matlab.ui.control.Label
        FullElectricPanel               matlab.ui.container.Panel
        InputConsumptionParGridLayout_3  matlab.ui.container.GridLayout
        FC_unitDieselLabel_19           matlab.ui.control.Label
        MotorEfficiencyFEEditField      matlab.ui.control.NumericEditField
        MotorEfficiencyEditFieldLabel_2  matlab.ui.control.Label
        FC_unitDieselLabel_18           matlab.ui.control.Label
        MotorEnergyFEEditField          matlab.ui.control.NumericEditField
        MotorEnergyEditFieldLabel_2     matlab.ui.control.Label
        ParallelHybridPanel             matlab.ui.container.Panel
        InputConsumptionParGridLayout_2  matlab.ui.container.GridLayout
        FC_unitDieselLabel_12           matlab.ui.control.Label
        MotorEfficiencyParEditField     matlab.ui.control.NumericEditField
        MotorEfficiencyEditFieldLabel   matlab.ui.control.Label
        FC_unitDieselLabel_11           matlab.ui.control.Label
        MotorEnergyParEditField         matlab.ui.control.NumericEditField
        MotorEnergyEditFieldLabel       matlab.ui.control.Label
        FC_unitDieselLabel_10           matlab.ui.control.Label
        EngineEfficiencyParEditField    matlab.ui.control.NumericEditField
        EngineEfficiencyEditField_2Label  matlab.ui.control.Label
        FC_unitDieselLabel_9            matlab.ui.control.Label
        WorkingTimeParEditField         matlab.ui.control.NumericEditField
        WorkingTimeEditFieldLabel_2     matlab.ui.control.Label
        FC_unitDieselLabel_8            matlab.ui.control.Label
        DieseldensityParEditField       matlab.ui.control.NumericEditField
        DieseldensityEditFieldLabel_2   matlab.ui.control.Label
        FC_unitDieselLabel_7            matlab.ui.control.Label
        SpecificDieselPowerParEditField  matlab.ui.control.NumericEditField
        LHVLabel                        matlab.ui.control.Label
        FC_unitDieselLabel_6            matlab.ui.control.Label
        FuelConsumptionParEditField     matlab.ui.control.NumericEditField
        FuelConsumptionEditField_3Label  matlab.ui.control.Label
        MaximumPanel                    matlab.ui.container.Panel
        MaximumGridLayout               matlab.ui.container.GridLayout
        umLabel                         matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        MaximumImpositionButtonGroup    matlab.ui.container.ButtonGroup
        SpeedButton                     matlab.ui.control.ToggleButton
        TorqueButton                    matlab.ui.control.ToggleButton
        StructurePanel                  matlab.ui.container.Panel
        StructureDropDown               matlab.ui.control.DropDown
        StructureDropDownLabel          matlab.ui.control.Label
        UploadaDutycycleButton          matlab.ui.control.Button
        DutyCycleResultTab              matlab.ui.container.Tab
        ResultGridLayout                matlab.ui.container.GridLayout
        DownloadPanel                   matlab.ui.container.Panel
        DownloadGridLayout              matlab.ui.container.GridLayout
        DownloadButton                  matlab.ui.control.Button
        speedUIAxes                     matlab.ui.control.UIAxes
        torqueUIAxes                    matlab.ui.control.UIAxes
        ElectricMotormapsTab            matlab.ui.container.Tab
        EffMapsGridLayout               matlab.ui.container.GridLayout
        PrinttheDutyCycleIntheMapsButton  matlab.ui.control.Button
        UploadEfficiencyMapButton       matlab.ui.control.Button
        EffMapsUIAxes                   matlab.ui.control.UIAxes
        ElectricMotorSelectionAssistantLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        default_duty_cycle = 'EPA_duty.txt'; % Description
        Duty_cycle_scaled;
        conversionMJ2kWh=0.277777778;
        torque_Nm;
        speed_rad_s;
        V_map;
        T_map;
        Eff_map;
        MapName='MotorMapExample.mat';
    end
    
    methods (Access = private)
        
        function ChangeInputBasedStructure(app)

            if (strcmp(app.StructureDropDown.Value,"Full Diesel"))
                  app.FullDieselPanel.Visible="on";
                  app.ParallelHybridPanel.Visible="off";
                  app.FullElectricPanel.Visible="off";
            elseif (strcmp(app.StructureDropDown.Value,"Hybrid"))
                app.FullDieselPanel.Visible="off";
                  app.ParallelHybridPanel.Visible="on";
                  app.FullElectricPanel.Visible="off";
            else
                app.FullDieselPanel.Visible="off";
                  app.ParallelHybridPanel.Visible="off";
                  app.FullElectricPanel.Visible="on";
            end
            
        end
        
        function plotDutyCycleSelected(app)
            yyaxis(app.DutyCycleUIAxes,"left")
            plot(app.DutyCycleUIAxes,app.Duty_cycle_scaled.time_sec,app.Duty_cycle_scaled.speed_perc,'LineWidth',2)
            xlabel(app.DutyCycleUIAxes,'Time [s]','FontSize',20);
            ylabel(app.DutyCycleUIAxes,'Speed [-]','FontSize',20);
            axis(app.DutyCycleUIAxes,[app.Duty_cycle_scaled.time_sec(1) app.Duty_cycle_scaled.time_sec(end) -0.05 1])
            yyaxis(app.DutyCycleUIAxes,"right")
            plot(app.DutyCycleUIAxes,app.Duty_cycle_scaled.time_sec,app.Duty_cycle_scaled.torque_perc,'LineWidth',2)
            ylabel(app.DutyCycleUIAxes,'Torque [-]','FontSize',20);
            grid(app.DutyCycleUIAxes,"on");
            axis(app.DutyCycleUIAxes,[app.Duty_cycle_scaled.time_sec(1) app.Duty_cycle_scaled.time_sec(end) -0.05 1])
            title(app.DutyCycleUIAxes,'Normalized Duty Cycle Selected','FontSize',20)
        end
        
        function [textData] = readText(fileName)
            % readText transforms m X n matrix from Mevea Solver plot txt
            % file to the same number of vector arrays.
            % n is equal to or less than 18 (number of %f64 s in the code).

            file = fopen(fileName);

            %ignore first n=2 lines
            for i = 1:2
                fgetl(file);
            end

            textData = [];
    
                while ~feof(file)
                    line = fgetl(file);
                    C = textscan(line,...
                        '%f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64 %f64',...
                        'Delimiter','	');
                    textData = [textData ; [C{:}]];
                end

            end
        
            function dutyCycleUpload(app,Name)
                data=readText(Name);
                app.Duty_cycle_scaled.time_sec=data(:,1);
                app.Duty_cycle_scaled.speed_perc=data(:,2)./100;
                app.Duty_cycle_scaled.torque_perc=data(:,3)./100;

            end
        
            function E_kWh = EnergyLoad_in_kWh(app)

            if (strcmp(app.StructureDropDown.Value,"Full Diesel"))
                FuelICE=app.FuelConsumptionDieselEditField.Value;
                SpecificDieselPower= app.SpecificDieselPowerDieselEditField.Value;% [MJ/kg]
                DieselDensity = app.DieseldensityDieselEditField.Value; % [kg/l]
                Efficiency_engine = app.EngineEfficiencyDieselEditField.Value;
                WorkingTime = app.WorkingTimeDieselEditField.Value;
                E_kWh = FuelICE.*SpecificDieselPower.*DieselDensity*Efficiency_engine.*app.conversionMJ2kWh/WorkingTime;
            elseif (strcmp(app.StructureDropDown.Value,"Hybrid"))
                FuelICE=app.FuelConsumptionParEditField.Value;
                SpecificDieselPower= app.SpecificDieselPowerParEditField.Value;% [MJ/kg]
                DieselDensity = app.DieseldensityParEditField.Value; % [kg/l]
                Efficiency_engine = app.EngineEfficiencyParEditField.Value;
                WorkingTime = app.WorkingTimeParEditField.Value;
                E_Eng_kWh=FuelICE.*SpecificDieselPower.*DieselDensity*Efficiency_engine.*app.conversionMJ2kWh/WorkingTime;
                Effciency_motor=app.MotorEfficiencyParEditField.Value;
                Motor_energy=app.MotorEnergyParEditField.Value;
                E_mot_kWh = Motor_energy*Effciency_motor;
                E_kWh=E_mot_kWh+E_Eng_kWh;
            else
               Effciency_motor=app.MotorEfficiencyFEEditField.Value;
                Motor_energy=app.MotorEnergyFEEditField.Value;
                E_kWh = Motor_energy*Effciency_motor;
            end 
            
        end
        
        
        function MaxSelection(app)
            if strcmp(app.MaximumImpositionButtonGroup.SelectedObject.Text,'Torque')
                app.MaximumEditFieldLabel.Text='Max Toque';
                app.umLabel.Text='[Nm]';
            else
                app.MaximumEditFieldLabel.Text='Max Speed';
                app.umLabel.Text='[rad/s]';
            end
        end
        
        function TorqueSpeedRequired(app,E_kWh)
            if strcmp(app.MaximumImpositionButtonGroup.SelectedObject.Text,'Torque')
                MaxTorque = app.MaximumEditField.Value;
                app.torque_Nm = MaxTorque.*app.Duty_cycle_scaled.torque_perc;
                E_kJ = E_kWh*3600;
                E_kJ_dutyCycle = E_kJ.*app.Duty_cycle_scaled.time_sec(end)/3600;
                MaxSpeed = E_kJ_dutyCycle*1e3/(sum(app.torque_Nm.*app.Duty_cycle_scaled.speed_perc.*(app.Duty_cycle_scaled.time_sec(2)-app.Duty_cycle_scaled.time_sec(1))));
                app.speed_rad_s = MaxSpeed.*app.Duty_cycle_scaled.speed_perc;
            else
                MaxSpeed = app.MaximumEditField.Value;
                app.speed_rad_s = MaxSpeed.*app.Duty_cycle_scaled.speed_perc;
                E_kJ = E_kWh*3600;
                E_kJ_dutyCycle = E_kJ.*app.Duty_cycle_scaled.time_sec(end)/3600;
                MaxTorque = E_kJ_dutyCycle*1e3/(sum(app.speed_rad_s.*app.Duty_cycle_scaled.torque_perc.*(app.Duty_cycle_scaled.time_sec(2)-app.Duty_cycle_scaled.time_sec(1))));
                app.torque_Nm = MaxTorque.*app.Duty_cycle_scaled.torque_perc;
            end
            
            app.StartLamp.Color = [0,1,0];

            plot(app.speedUIAxes,app.Duty_cycle_scaled.time_sec,app.speed_rad_s,'LineWidth',2)
            xlabel(app.speedUIAxes,'Time [s]','FontSize',20);
            ylabel(app.speedUIAxes,'Speed $[\frac{rad}{s}]$','Interpreter','latex','FontSize',20);
            grid(app.speedUIAxes,"on")
            axis(app.speedUIAxes,[app.Duty_cycle_scaled.time_sec(1) app.Duty_cycle_scaled.time_sec(end) -5 MaxSpeed])
            title(app.speedUIAxes,'Speed','FontSize',20)

            plot(app.torqueUIAxes,app.Duty_cycle_scaled.time_sec,app.torque_Nm,'LineWidth',2)
            xlabel(app.torqueUIAxes,'Time [s]','FontSize',20);
            ylabel(app.torqueUIAxes,'Torque [Nm]','FontSize',20);
            grid(app.torqueUIAxes,"on")
            axis(app.torqueUIAxes,[app.Duty_cycle_scaled.time_sec(1) app.Duty_cycle_scaled.time_sec(end) -5 MaxTorque])
            title(app.torqueUIAxes,'Torque','FontSize',20)
        end

        function readyToCalcolate(app)
            if (strcmp(app.StructureDropDown.Value,"Full Diesel"))
                CheckFuel = app.FuelConsumptionDieselEditField.Value>0;
                CheckSPD = app.SpecificDieselPowerDieselEditField.Value>0;% [MJ/kg]
                CheckDD = app.DieseldensityDieselEditField.Value>0; % [kg/l]
                CheckEffEng = app.EngineEfficiencyDieselEditField.Value>0;
                CheckWT = app.WorkingTimeDieselEditField.Value>0;
                CheckStruct = CheckFuel && CheckSPD && CheckDD && CheckEffEng && CheckWT;
            elseif (strcmp(app.StructureDropDown.Value,"Hybrid"))
                CheckFuel = app.FuelConsumptionParEditField.Value>=0;
                CheckSPD = app.SpecificDieselPowerParEditField.Value>0;% [MJ/kg]
                CheckDD = app.DieseldensityParEditField.Value>0; % [kg/l]
                CheckEffEng = app.EngineEfficiencyParEditField.Value>0;
                CheckWT = app.WorkingTimeParEditField.Value>0;
                CheckEffMot = app.MotorEfficiencyParEditField.Value>0;
                CheckMotEn = app.MotorEnergyParEditField.Value>=0;
                CheckStruct = CheckFuel && CheckSPD && CheckDD && CheckEffEng && CheckWT  && CheckEffMot && CheckMotEn;
            else
                CheckEffMot = app.MotorEfficiencyParEditField.Value>0;
                CheckMotEn = app.MotorEnergyParEditField.Value>0;
                CheckStruct = CheckEffMot && CheckMotEn;
            end

            if app.MaximumEditField.Value>0 && CheckStruct
                app.StartLamp.Enable="on";
                app.StartButton.Enable="on";
            else
                app.StartLamp.Enable="off";
                app.StartButton.Enable = "off";
            end
            app.StartLamp.Color = [1.00,1.00,0.07];
        end
        
        function plotEfficiencyMap(app)
                hold(app.EffMapsUIAxes,"off")
                contourf(app.EffMapsUIAxes,app.V_map, app.T_map, app.Eff_map,'ShowText','on','levels',2.5);
                colormap(app.EffMapsUIAxes,'jet')
                colorbar(app.EffMapsUIAxes)
                xlabel(app.EffMapsUIAxes,'speed $[\frac{rad}{s}]$','Interpreter','Latex','FontSize',20)
                ylabel(app.EffMapsUIAxes,'Torque $[Nm]$','Interpreter','Latex','FontSize',20)
                title(app.EffMapsUIAxes,'Electric motor efficiency map','FontSize',20)
                set(app.EffMapsUIAxes,"FontSize",20)
        end
        
        function LoadMap(app)
            [~,~,ext] = fileparts(app.MapName);
            if strcmp(ext,'.mat')
                load(app.MapName);
                if isrow(T_map)
                    app.T_map=T_map;
                else
                    app.T_map=T_map';
                end
                if isrow(V_map)
                    app.V_map=V_map;
                else
                    app.V_map=V_map';
                end
                [o,v]=size(Eff_map);
                if length(V_map)==o
                    app.Eff_map=Eff_map';
                else
                    app.Eff_map=Eff_map;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ChangeInputBasedStructure;
            app.dutyCycleUpload(app.default_duty_cycle);
            app.plotDutyCycleSelected;
            app.MaxSelection;
            app.StartLamp.Color = [1.00,1.00,0.07];
            app.StartLamp.Enable = "off";
            app.StartButton.Enable = "off";
            app.DownloadButton.Enable="off";
            app.PrinttheDutyCycleIntheMapsButton.Enable='off';
            app.readyToCalcolate;
            app.LoadMap;
            app.plotEfficiencyMap;
        end

        % Value changed function: StructureDropDown
        function StructureDropDownValueChanged(app, event)
           app.ChangeInputBasedStructure;
        end

        % Selection changed function: MaximumImpositionButtonGroup
        function MaximumImpositionButtonGroupSelectionChanged(app, event)
            app.MaxSelection;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % need to add a disble Function
            E=app.EnergyLoad_in_kWh;
            app.TorqueSpeedRequired(E);
            app.DownloadButton.Enable="on";
            app.PrinttheDutyCycleIntheMapsButton.Enable='on';
        end

        % Value changed function: MaximumEditField
        function MaximumEditFieldValueChanged(app, event)
            app.readyToCalcolate;
        end

        % Value changed function: FuelConsumptionParEditField
        function FuelConsumptionParEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: SpecificDieselPowerParEditField
        function SpecificDieselPowerParEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: DieseldensityParEditField
        function DieseldensityParEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: WorkingTimeParEditField
        function WorkingTimeParEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: EngineEfficiencyParEditField
        function EngineEfficiencyParEditFieldValueChanged(app, event)
            app.readyToCalcolate;
        end

        % Value changed function: MotorEnergyParEditField
        function MotorEnergyParEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: MotorEfficiencyParEditField
        function MotorEfficiencyParEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: FuelConsumptionDieselEditField
        function FuelConsumptionDieselEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: SpecificDieselPowerDieselEditField
        function SpecificDieselPowerDieselEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: DieseldensityDieselEditField
        function DieseldensityDieselEditFieldValueChanged(app, event)
            app.readyToCalcolate;
        end

        % Value changed function: WorkingTimeDieselEditField
        function WorkingTimeDieselEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: EngineEfficiencyDieselEditField
        function EngineEfficiencyDieselEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: MotorEnergyFEEditField
        function MotorEnergyFEEditFieldValueChanged(app, event)
             app.readyToCalcolate;
        end

        % Value changed function: MotorEfficiencyFEEditField
        function MotorEfficiencyFEEditFieldValueChanged(app, event)
            app.readyToCalcolate;
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            % prompt = {'Enter duty cycle name, without spaces and special charaters:'};
            % dlgtitle = 'Input';
            % fieldsize = [1 45];
            % definput = {'duty_cycle_name','hsv'};
            % DutyCycleName = inputdlg(prompt,dlgtitle,fieldsize,definput)
            duty_cycle.time=app.Duty_cycle_scaled.time_sec;
            duty_cycle.torque=app.torque_Nm;
            duty_cycle.speed=app.speed_rad_s;
            % assignin('base',DutyCycleName{1},s)
            prompt = {'Enter name of file, without spaces and special charaters:'};
            dlgtitle = 'Input';
            fieldsize = [1 45];
            definput = {'Result','hsv'};
            try
            FileName = inputdlg(prompt,dlgtitle,fieldsize,definput);
            [filepath,name,ext] = fileparts(FileName)
            if strcmp(ext{1},'.mat') || isempty(ext{1})
                save(FileName{1},"duty_cycle")
            elseif strcmp(ext{1},'.txt') || strcmp(ext{1},'.csv')  || strcmp(ext{1},'.xls') 
                T = table(duty_cycle.time,duty_cycle.speed,  duty_cycle.torque, 'VariableNames', { 'Time [s]', 'Speed [rad/s]','Torque [Nm]'} );
                
                writetable(T, FileName{1});
            else
                causeException = MException('File extention not valid',msg);
            end
            catch
               fig = uifigure;
                uialert(fig,'The file name is not valid, that can be due to the wrong data extention','Input not valid');
            end
        end

        % Button pushed function: UploadaDutycycleButton
        function UploadaDutycycleButtonPushed(app, event)
            [file,path] = uigetfile;
            addpath(path);
            app.dutyCycleUpload(file);
            app.plotDutyCycleSelected;
        end

        % Button pushed function: PrinttheDutyCycleIntheMapsButton
        function PrinttheDutyCycleIntheMapsButtonPushed(app, event)
            hold(app.EffMapsUIAxes,"on")
            plot(app.EffMapsUIAxes,app.speed_rad_s,app.torque_Nm,'ws','LineWidth',2)
        end

        % Button pushed function: UploadEfficiencyMapButton
        function UploadEfficiencyMapButtonPushed(app, event)
            [file,path] = uigetfile;
            addpath(path);
            app.MapName=file;
            app.LoadMap;
            app.plotEfficiencyMap;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.ElectricMotorSelectionAssistantLabel = uilabel(app.MainGridLayout);
            app.ElectricMotorSelectionAssistantLabel.Text = 'Electric Motor Selection Assistant';
            app.MainTabGroup = uitabgroup(app.MainGridLayout);
            app.WorkingConditionTab = uitab(app.MainTabGroup);
            app.WorkingConditionTab.Title = 'Working Condition';
            app.WCGridLayout = uigridlayout(app.WorkingConditionTab);
            app.InputPanel = uipanel(app.WCGridLayout);
            app.GridLayout = uigridlayout(app.InputPanel);
            app.UploadaDutycycleButton = uibutton(app.GridLayout, 'push');
            app.UploadaDutycycleButton.ButtonPushedFcn = createCallbackFcn(app, @UploadaDutycycleButtonPushed, true);
            app.UploadaDutycycleButton.Text = 'Upload a Duty cycle';
            app.StructurePanel = uipanel(app.GridLayout);
            app.StructureDropDownLabel = uilabel(app.StructurePanel);
            app.StructureDropDownLabel.Text = 'Structure';
            app.StructureDropDown = uidropdown(app.StructurePanel);
            app.StructureDropDown.Items = {'Full Diesel', 'Hybrid', 'Full Electric'};
            app.StructureDropDown.ValueChangedFcn = createCallbackFcn(app, @StructureDropDownValueChanged, true);
            app.StructureDropDown.Value = 'Full Diesel';
            app.MaximumPanel = uipanel(app.GridLayout);
            app.MaximumGridLayout = uigridlayout(app.MaximumPanel);
            app.MaximumImpositionButtonGroup = uibuttongroup(app.MaximumGridLayout);
            app.MaximumImpositionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaximumImpositionButtonGroupSelectionChanged, true);
            app.MaximumImpositionButtonGroup.Title = 'Maximum Imposition';
            app.TorqueButton = uitogglebutton(app.MaximumImpositionButtonGroup);
            app.TorqueButton.Text = 'Torque';
            app.TorqueButton.Value = true;
            app.SpeedButton = uitogglebutton(app.MaximumImpositionButtonGroup);
            app.SpeedButton.Text = 'Speed';
            app.MaximumEditFieldLabel = uilabel(app.MaximumGridLayout);
            app.MaximumEditFieldLabel.Text = 'Maximum ';
            app.MaximumEditField = uieditfield(app.MaximumGridLayout, 'numeric');
            app.MaximumEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumEditFieldValueChanged, true);
            app.umLabel = uilabel(app.MaximumGridLayout);
            app.umLabel.Text = '[u.m]';
            app.ParallelHybridPanel = uipanel(app.GridLayout);
            app.InputConsumptionParGridLayout_2 = uigridlayout(app.ParallelHybridPanel);
            app.FuelConsumptionEditField_3Label = uilabel(app.InputConsumptionParGridLayout_2);
            app.FuelConsumptionEditField_3Label.Text = 'Fuel Consumption';
            app.FuelConsumptionParEditField = uieditfield(app.InputConsumptionParGridLayout_2, 'numeric');
            app.FuelConsumptionParEditField.ValueChangedFcn = createCallbackFcn(app, @FuelConsumptionParEditFieldValueChanged, true);
            app.FuelConsumptionParEditField.Value = 63.8;
            app.FC_unitDieselLabel_6 = uilabel(app.InputConsumptionParGridLayout_2);
            app.FC_unitDieselLabel_6.Text = '[l]';
            app.LHVLabel = uilabel(app.InputConsumptionParGridLayout_2);
            app.LHVLabel.Text = 'LHV';
            app.SpecificDieselPowerParEditField = uieditfield(app.InputConsumptionParGridLayout_2, 'numeric');
            app.SpecificDieselPowerParEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificDieselPowerParEditFieldValueChanged, true);
            app.SpecificDieselPowerParEditField.Value = 42.6;
            app.FC_unitDieselLabel_7 = uilabel(app.InputConsumptionParGridLayout_2);
            app.FC_unitDieselLabel_7.Text = '[MJ/kg]';
            app.DieseldensityEditFieldLabel_2 = uilabel(app.InputConsumptionParGridLayout_2);
            app.DieseldensityEditFieldLabel_2.Text = 'Diesel density';
            app.DieseldensityParEditField = uieditfield(app.InputConsumptionParGridLayout_2, 'numeric');
            app.DieseldensityParEditField.ValueChangedFcn = createCallbackFcn(app, @DieseldensityParEditFieldValueChanged, true);
            app.DieseldensityParEditField.Value = 0.846;
            app.FC_unitDieselLabel_8 = uilabel(app.InputConsumptionParGridLayout_2);
            app.FC_unitDieselLabel_8.Text = '[kg/l]';
            app.WorkingTimeEditFieldLabel_2 = uilabel(app.InputConsumptionParGridLayout_2);
            app.WorkingTimeEditFieldLabel_2.Text = 'Working Time';
            app.WorkingTimeParEditField = uieditfield(app.InputConsumptionParGridLayout_2, 'numeric');
            app.WorkingTimeParEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingTimeParEditFieldValueChanged, true);
            app.WorkingTimeParEditField.Value = 8;
            app.FC_unitDieselLabel_9 = uilabel(app.InputConsumptionParGridLayout_2);
            app.FC_unitDieselLabel_9.Text = '[h]';
            app.EngineEfficiencyEditField_2Label = uilabel(app.InputConsumptionParGridLayout_2);
            app.EngineEfficiencyEditField_2Label.Text = 'Engine Efficiency';
            app.EngineEfficiencyParEditField = uieditfield(app.InputConsumptionParGridLayout_2, 'numeric');
            app.EngineEfficiencyParEditField.Limits = [0 1];
            app.EngineEfficiencyParEditField.ValueChangedFcn = createCallbackFcn(app, @EngineEfficiencyParEditFieldValueChanged, true);
            app.EngineEfficiencyParEditField.Value = 0.3;
            app.FC_unitDieselLabel_10 = uilabel(app.InputConsumptionParGridLayout_2);
            app.FC_unitDieselLabel_10.Text = '[-]';
            app.MotorEnergyEditFieldLabel = uilabel(app.InputConsumptionParGridLayout_2);
            app.MotorEnergyEditFieldLabel.Text = 'Motor Energy';
            app.MotorEnergyParEditField = uieditfield(app.InputConsumptionParGridLayout_2, 'numeric');
            app.MotorEnergyParEditField.ValueChangedFcn = createCallbackFcn(app, @MotorEnergyParEditFieldValueChanged, true);
            app.MotorEnergyParEditField.Value = 1.71;
            app.FC_unitDieselLabel_11 = uilabel(app.InputConsumptionParGridLayout_2);
            app.FC_unitDieselLabel_11.Text = '[kWh]';
            app.MotorEfficiencyEditFieldLabel = uilabel(app.InputConsumptionParGridLayout_2);
            app.MotorEfficiencyEditFieldLabel.Text = 'Motor Efficiency';
            app.MotorEfficiencyParEditField = uieditfield(app.InputConsumptionParGridLayout_2, 'numeric');
            app.MotorEfficiencyParEditField.Limits = [0 1];
            app.MotorEfficiencyParEditField.ValueChangedFcn = createCallbackFcn(app, @MotorEfficiencyParEditFieldValueChanged, true);
            app.MotorEfficiencyParEditField.Value = 0.85;
            app.FC_unitDieselLabel_12 = uilabel(app.InputConsumptionParGridLayout_2);
            app.FC_unitDieselLabel_12.Text = '[-]';
            app.FullElectricPanel = uipanel(app.GridLayout);
            app.InputConsumptionParGridLayout_3 = uigridlayout(app.FullElectricPanel);
            app.MotorEnergyEditFieldLabel_2 = uilabel(app.InputConsumptionParGridLayout_3);
            app.MotorEnergyEditFieldLabel_2.Text = 'Motor Energy';
            app.MotorEnergyFEEditField = uieditfield(app.InputConsumptionParGridLayout_3, 'numeric');
            app.MotorEnergyFEEditField.ValueChangedFcn = createCallbackFcn(app, @MotorEnergyFEEditFieldValueChanged, true);
            app.MotorEnergyFEEditField.Value = 23.7;
            app.FC_unitDieselLabel_18 = uilabel(app.InputConsumptionParGridLayout_3);
            app.FC_unitDieselLabel_18.Text = '[kWh]';
            app.MotorEfficiencyEditFieldLabel_2 = uilabel(app.InputConsumptionParGridLayout_3);
            app.MotorEfficiencyEditFieldLabel_2.Text = 'Motor Efficiency';
            app.MotorEfficiencyFEEditField = uieditfield(app.InputConsumptionParGridLayout_3, 'numeric');
            app.MotorEfficiencyFEEditField.Limits = [0 1];
            app.MotorEfficiencyFEEditField.ValueChangedFcn = createCallbackFcn(app, @MotorEfficiencyFEEditFieldValueChanged, true);
            app.MotorEfficiencyFEEditField.Value = 0.85;
            app.FC_unitDieselLabel_19 = uilabel(app.InputConsumptionParGridLayout_3);
            app.FC_unitDieselLabel_19.Text = '[-]';
            app.FullDieselPanel = uipanel(app.GridLayout);
            app.InputConsumptionFulDieselGridLayout = uigridlayout(app.FullDieselPanel);
            app.FuelConsumptionEditFieldLabel = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.FuelConsumptionEditFieldLabel.Text = 'Fuel Consumption';
            app.FuelConsumptionDieselEditField = uieditfield(app.InputConsumptionFulDieselGridLayout, 'numeric');
            app.FuelConsumptionDieselEditField.ValueChangedFcn = createCallbackFcn(app, @FuelConsumptionDieselEditFieldValueChanged, true);
            app.FuelConsumptionDieselEditField.Value = 41.3;
            app.FC_unitDieselLabel = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.FC_unitDieselLabel.Text = '[l]';
            app.LHVLabel_2 = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.LHVLabel_2.Text = 'LHV';
            app.SpecificDieselPowerDieselEditField = uieditfield(app.InputConsumptionFulDieselGridLayout, 'numeric');
            app.SpecificDieselPowerDieselEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificDieselPowerDieselEditFieldValueChanged, true);
            app.SpecificDieselPowerDieselEditField.Value = 42.6;
            app.FC_unitDieselLabel_2 = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.FC_unitDieselLabel_2.Text = '[MJ/kg]';
            app.DieseldensityEditFieldLabel = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.DieseldensityEditFieldLabel.Text = 'Diesel density';
            app.DieseldensityDieselEditField = uieditfield(app.InputConsumptionFulDieselGridLayout, 'numeric');
            app.DieseldensityDieselEditField.ValueChangedFcn = createCallbackFcn(app, @DieseldensityDieselEditFieldValueChanged, true);
            app.DieseldensityDieselEditField.Value = 0.846;
            app.FC_unitDieselLabel_3 = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.FC_unitDieselLabel_3.Text = '[kg/l]';
            app.WorkingTimeEditFieldLabel = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.WorkingTimeEditFieldLabel.Text = 'Working Time';
            app.WorkingTimeDieselEditField = uieditfield(app.InputConsumptionFulDieselGridLayout, 'numeric');
            app.WorkingTimeDieselEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingTimeDieselEditFieldValueChanged, true);
            app.WorkingTimeDieselEditField.Value = 8;
            app.FC_unitDieselLabel_4 = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.FC_unitDieselLabel_4.Text = '[h]';
            app.EngineEfficiencyEditFieldLabel = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.EngineEfficiencyEditFieldLabel.Text = 'Engine Efficiency';
            app.EngineEfficiencyDieselEditField = uieditfield(app.InputConsumptionFulDieselGridLayout, 'numeric');
            app.EngineEfficiencyDieselEditField.Limits = [0 1];
            app.EngineEfficiencyDieselEditField.ValueChangedFcn = createCallbackFcn(app, @EngineEfficiencyDieselEditFieldValueChanged, true);
            app.EngineEfficiencyDieselEditField.Value = 0.3;
            app.FC_unitDieselLabel_5 = uilabel(app.InputConsumptionFulDieselGridLayout);
            app.FC_unitDieselLabel_5.Text = '[-]';
            app.VisualizationAndStartGridLayout = uigridlayout(app.WCGridLayout);
            app.DutyCycleUIAxes = uiaxes(app.VisualizationAndStartGridLayout);
            title(app.DutyCycleUIAxes, 'Title')
            xlabel(app.DutyCycleUIAxes, 'X')
            ylabel(app.DutyCycleUIAxes, 'Y')
            zlabel(app.DutyCycleUIAxes, 'Z')
            app.StartPanel = uipanel(app.VisualizationAndStartGridLayout);
            app.StartGridLayout = uigridlayout(app.StartPanel);
            app.StartButton = uibutton(app.StartGridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StartLamp = uilamp(app.StartGridLayout);
            app.DutyCycleResultTab = uitab(app.MainTabGroup);
            app.DutyCycleResultTab.Title = 'Duty Cycle Result';
            app.ResultGridLayout = uigridlayout(app.DutyCycleResultTab);
            app.torqueUIAxes = uiaxes(app.ResultGridLayout);
            title(app.torqueUIAxes, 'Title')
            xlabel(app.torqueUIAxes, 'X')
            ylabel(app.torqueUIAxes, 'Y')
            zlabel(app.torqueUIAxes, 'Z')
            app.speedUIAxes = uiaxes(app.ResultGridLayout);
            title(app.speedUIAxes, 'Title')
            xlabel(app.speedUIAxes, 'X')
            ylabel(app.speedUIAxes, 'Y')
            zlabel(app.speedUIAxes, 'Z')
            app.DownloadPanel = uipanel(app.ResultGridLayout);
            app.DownloadGridLayout = uigridlayout(app.DownloadPanel);
            app.DownloadButton = uibutton(app.DownloadGridLayout, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.ElectricMotormapsTab = uitab(app.MainTabGroup);
            app.ElectricMotormapsTab.Title = 'Electric Motor maps';
            app.EffMapsGridLayout = uigridlayout(app.ElectricMotormapsTab);
            app.EffMapsUIAxes = uiaxes(app.EffMapsGridLayout);
            title(app.EffMapsUIAxes, 'Title')
            xlabel(app.EffMapsUIAxes, 'X')
            ylabel(app.EffMapsUIAxes, 'Y')
            zlabel(app.EffMapsUIAxes, 'Z')
            app.UploadEfficiencyMapButton = uibutton(app.EffMapsGridLayout, 'push');
            app.UploadEfficiencyMapButton.ButtonPushedFcn = createCallbackFcn(app, @UploadEfficiencyMapButtonPushed, true);
            app.UploadEfficiencyMapButton.Text = 'Upload Efficiency Map';
            app.PrinttheDutyCycleIntheMapsButton = uibutton(app.EffMapsGridLayout, 'push');
            app.PrinttheDutyCycleIntheMapsButton.ButtonPushedFcn = createCallbackFcn(app, @PrinttheDutyCycleIntheMapsButtonPushed, true);
            app.PrinttheDutyCycleIntheMapsButton.Text = 'Print the Duty Cycle In the  Maps';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DutyCycleApp2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end